classdef thresholdGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThresholdGUIUIFigure    matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        panel_location          matlab.ui.container.Panel
        button_getExpt          matlab.ui.control.Button
        button_save             matlab.ui.control.Button
        ProbeAreaLabel_2        matlab.ui.control.Label
        dropdown_probe2         matlab.ui.control.DropDown
        ProbeAreaLabel          matlab.ui.control.Label
        dropdown_probe1         matlab.ui.control.DropDown
        dropdown_area1          matlab.ui.control.DropDown
        dropdown_area2          matlab.ui.control.DropDown
        SettingsButton          matlab.ui.control.Button
        panel_fileInfo          matlab.ui.container.Panel
        table_fileInfo          matlab.ui.control.Table
        RightPanel              matlab.ui.container.Panel
        panel_signal            matlab.ui.container.Panel
        axis_snippet            matlab.ui.control.UIAxes
        ThresholdSliderLabel    matlab.ui.control.Label
        slider_threshold        matlab.ui.control.Slider
        button_randomSnippet    matlab.ui.control.Button
        button_nextChan         matlab.ui.control.Button
        button_prevChan         matlab.ui.control.Button
        button_rejectChan       matlab.ui.control.Button
        axis_spikePreview       matlab.ui.control.UIAxes
        panel_extract           matlab.ui.container.Panel
        radio_compSelect        matlab.ui.container.ButtonGroup
        radio_local             matlab.ui.control.RadioButton
        radio_remote            matlab.ui.control.RadioButton
        remoteipEditFieldLabel  matlab.ui.control.Label
        text_ip                 matlab.ui.control.EditField
        button_checkConn        matlab.ui.control.Button
        button_copyData         matlab.ui.control.Button
        button_extract          matlab.ui.control.Button
        button_firstSnippet     matlab.ui.control.Button
        button_lastSnippet      matlab.ui.control.Button
        text_channelNum         matlab.ui.control.NumericEditField
        button_timePrev         matlab.ui.control.Button
        button_timeNext         matlab.ui.control.Button
        JumptotimeLabel         matlab.ui.control.Label
        ChannelLabel            matlab.ui.control.Label
        panel_probe             matlab.ui.container.Panel
        axis_probe              matlab.ui.control.UIAxes
        label_selectedProbe     matlab.ui.control.Label
        button_nextProbe        matlab.ui.control.Button
        button_prevProbe        matlab.ui.control.Button
        ProbeLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        settings
        exptName
        probe
        sampleFreq
        nChannels
        nProbes
        thresholds
        badChannels
        nTotalSamples
        snippet
        butterParams
        
        currentProbeNum
        currentChannel
        currentTc
        currentStart
        
        thresholdLineHandle
        signalLineHandle
        channelMarkerHandle
    end
    
    methods (Access = private)
        
        function refreshFileInfo(app)
            % display Settings
            % display FileProps
            
            data = {
                'expt name' '';...
                'exp loc' app.settings.expFolder;...
                'n probes' 'XXX';...
                'n channels' 'XXX';...
                'sample freq' 'XXX';...
                'n bad chans' '0';...
                
                'high pass' num2str(app.settings.filterHighPass);...
                'low pass' num2str(app.settings.filterLowPass);...
                
                'name' app.settings.name;...
                'email' app.settings.email;...
                
                };
            
            app.table_fileInfo.Data = cell2table(data);
        end
        
        function refreshRemoteInfo(app)
            if ~isfield(app.settings,'doRemote')
                app.settings.doRemote = false;
                app.settings.remoteIP = '';
            end
            
            if app.settings.doRemote
                app.radio_compSelect.SelectedObject = app.radio_remote;
                app.text_ip.Value = app.settings.remoteIP;
                app.text_ip.Enable = true;
                app.button_checkConn.Enable = true;
                app.button_copyData.Enable = true;
            else
                app.radio_compSelect.SelectedObject = app.radio_local;
                app.text_ip.Value = app.settings.remoteIP;
                app.text_ip.Enable = false;
                app.button_checkConn.Enable = false;
                app.button_copyData.Enable = false;
            end
            
            doRemote = app.settings.doRemote;
            remoteIP = app.settings.remoteIP;
        end
        
        function refreshSnippet(app,getNew,pos)
            if getNew
                app.getNewSnippet(pos);
            end
            
            cla(app.axis_snippet);
            app.currentTc = filter(app.butterParams.b1, app.butterParams.a1, app.snippet(app.currentChannel,:));
            app.signalLineHandle = plot(app.axis_snippet,linspace(0,1000,app.sampleFreq),app.currentTc,'k');
            hold(app.axis_snippet,'on')
            
            if app.badChannels(app.currentChannel)
                app.button_rejectChan.Text = 'Accept Channel';
                set(app.axis_snippet,'color',[0.6 0.3 0.3])
            else
                app.button_rejectChan.Text = 'Reject Channel';
                set(app.axis_snippet,'color','w')
            end
            app.plotThreshold()
            app.highlightChannel()
            app.previewSpikes()
        end
        
        function getNewSnippet(app,pos)
            dataFileName = [app.settings.expFolder '/' app.exptName '/' app.exptName '_amplifier.dat'];
            DataFile = fopen(dataFileName,'r');
            
            frewind(DataFile);
            switch pos
                case 'first'
                    app.currentStart = 0;
                case 'last'
                    %note: nTotalSamples is in samples per channel (not in bytes in file) 
                    app.currentStart = app.nTotalSamples - app.sampleFreq;
                case 'random'
                    app.currentStart = randi(app.nTotalSamples - app.sampleFreq);
                case 'prev'
                    app.currentStart = max(app.currentStart-0.5*app.sampleFreq,0);
                case 'next'
                    app.currentStart = min(app.currentStart+0.5*app.sampleFreq,app.nTotalSamples - app.sampleFreq);    
                otherwise
            end
            fseek(DataFile,2*app.currentStart*app.nChannels,'bof');
            
            app.snippet = fread(DataFile, [app.nChannels app.sampleFreq], 'int16');
        end
        
        function plotThreshold(app)
            if ~isempty(app.thresholdLineHandle)
                if isvalid(app.thresholdLineHandle)
                    delete(app.thresholdLineHandle);
                end
            end
            app.thresholdLineHandle = line(app.axis_snippet,[0 1000],[app.thresholds(app.currentChannel) app.thresholds(app.currentChannel)],'color',[0.7 0.3 0.2],'linewidth',2);
            app.previewSpikes();
            set(app.axis_snippet,'YLim',sort(app.thresholds(app.currentChannel)*[2 -2]));
        end
        
        function refreshProbe(app)
            cla(app.axis_probe);
            if isfield(app.probe,'type')
                app.label_selectedProbe.Text = num2str(app.currentProbeNum);
                probeTmp = app.probe(app.currentProbeNum);
                plot(app.axis_probe,probeTmp.x,probeTmp.z,'sqr', 'MarkerSize',11);
                hold(app.axis_probe,"on");
                for i=1:probeTmp.nChannels
                    text(probeTmp.x(i)-5,probeTmp.z(i),...
                        num2str(probeTmp.channels(i)+1),'FontSize',9,'parent',app.axis_probe);
                end
                axis(app.axis_probe,[min(probeTmp.x)-50 max(probeTmp.x)+50 min(probeTmp.z)-50 max(probeTmp.z)+50]);
                axis(app.axis_probe,'equal');
                set(app.axis_probe,'FontSize',10,'TickDir','out');
                app.highlightChannel();
            end
        end
        
        function highlightChannel(app)
            if isfield(app.probe,'type')
                if ~isempty(app.channelMarkerHandle)
                    delete(app.channelMarkerHandle);
                end
                probeTmp = app.probe(app.currentProbeNum);
                cc = app.currentChannel;
                if app.currentProbeNum == 2 && (cc > app.probe(1).nChannels)
                    cc = cc - app.probe(1).nChannels;
                    app.channelMarkerHandle = plot(app.axis_probe,probeTmp.x(cc),probeTmp.z(cc),'bo', 'MarkerSize',11,'LineWidth',2);
                elseif app.currentProbeNum == 1 && (cc <= app.probe(1).nChannels)
                    app.channelMarkerHandle = plot(app.axis_probe,probeTmp.x(cc),probeTmp.z(cc),'bo', 'MarkerSize',11,'LineWidth',2);
                end
            end
        end
        
        function previewSpikes(app)
            sig = app.currentTc;
            th = app.thresholds(app.currentChannel);
            
            %find crossings - we want the transitions
            if th<0
                crossings = find(sig(2:end)<th & sig(1:end-1)>=th);
            else
                crossings = find(sig(2:end)>th & sig(1:end-1)<=th);
            end
            
            
            crossings(crossings<61) = [];
            crossings(crossings+60>length(sig)) = [];
            tt = linspace(-60*1000/app.sampleFreq,60*1000/app.sampleFreq,121);
            
            cla(app.axis_spikePreview);
            if ~isempty(crossings)
                if length(crossings) > 100
                    crossings = crossings(randperm(length(crossings),100));
                end
                clip = nan(length(crossings),121);
                for ii=1:length(crossings)
                    clip(ii,:) = sig(crossings(ii)-60:crossings(ii)+60);
                end
                plot(app.axis_spikePreview,tt,clip','k');
                grid(app.axis_spikePreview,'on');
            end
        end
        
    end
    
    methods (Access = public)
        
        function getSettings(app,settings)
            app.settings=settings;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('microprobe_wiring/')
            addpath('microprobe_wiring/headstage and pcb wiring/')
            
            %initialize settings
            app.settings.name='Nielsen Lab';
            app.settings.email='nielsenlabmbi@gmail.com';
            app.settings.remoteIP='172.30.6.202';
            app.settings.doRemote=1;
            app.settings.filterHighPass = 250;
            app.settings.filterLowPass = 5000;
            app.settings.expFolder = 'z:\ephys_new';
            app.refreshFileInfo();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ThresholdGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {461, 461};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SettingsButton
        function button_settingsButtonPushed(app, event)
            %get settings
            settingsHandle=thresholdGuiSettings(app);
            waitfor(settingsHandle)
            
            %execute startup functions
            app.refreshFileInfo();
            %app.refreshRemoteInfo();
        end

        % Button pushed function: button_getExpt
        function button_getExptPushed(app, event)
            %get experiment folder and name
            f = uigetdir(app.settings.expFolder,'Please select the experiment folder');
            [expFolder,app.exptName]=fileparts(f);
            app.settings.expFolder = expFolder;
            app.table_fileInfo.Data{1,2}{1} = app.exptName;
            app.table_fileInfo.Data{2,2}{1} = expFolder;
            
            %read intan header
            header=read_Intan_Header(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_info.rhd']));
            
            %get sampling frequency and update table
            app.sampleFreq = header.sample_rate;
            app.table_fileInfo.Data{5,2}{1} = num2str(app.sampleFreq);
            
            %check whether ID file exists
            idFileExists = exist(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_id.mat']),'file');
            
            if ~idFileExists %file not opened before, make guesses based on intan header
                %if there are 2 probes, port A and B should be active
                %look for port A first - this will usually be the
                %headstage present if only one is used
                portIdx=find(strcmp(header.signal_group_name,'Port A')==1);
                if ~isempty(portIdx)
                    portEnable=header.signal_group_enabled(portIdx);
                    if portEnable
                        app.probe(1).nChannels=header.signal_group_num_amp_enabled(portIdx); %this just prevents errors of mismatch between data and loaded probe
                        app.nChannels=app.probe(1).nChannels;
                        app.nProbes=1;
                    end
                end
                
                %look for port B
                portIdx=find(strcmp(header.signal_group_name,'Port B')==1);
                if ~isempty(portIdx)
                    portEnable=header.signal_group_enabled(portIdx);
                    if portEnable
                        app.dropdown_area2.Enable = true;
                        app.probe(2).nChannels=header.signal_group_num_amp_enabled(portIdx); %this just prevents errors of mismatch between data and loaded probe
                        app.nChannels=app.nChannels+app.probe(2).nChannels;
                        app.nProbes=2;
                    end
                end
                
                %update table, dropdowns etc
                app.table_fileInfo.Data{3,2}{1} = num2str(app.nProbes);
                app.dropdown_area1.Enable=1;
                app.dropdown_probe1.Enable=1;
                if app.nProbes==2
                    app.dropdown_area2.Enable = true;
                    app.dropdown_probe2.Enable = 1;
                    app.table_fileInfo.Data{4,2}{1} = [num2str(app.probe(1).nChannels) ', ' num2str(app.probe(2).nChannels)];
                else
                    app.dropdown_probe2.Value = 'select';
                    app.dropdown_probe2.Enable=0;
                    app.dropdown_area2.Value='select';
                    app.dropdown_area2.Enable=0;
                    app.table_fileInfo.Data{4,2}{1} = num2str(app.probe(1).nChannels);
                end
                
            else  %file already opened before, so use the previous settings
                load(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_id.mat']),'id')
                
                if ~strcmp(app.settings.name,id.name)
                    ButtonName = questdlg(['This file was processed by ' id.name '. Change to your name?'],...
                        'Name Mismatch', 'Yes','No','Yes');
                    if strcmp(ButtonName,'No')
                        app.settings.name = id.name;
                        app.settings.email = id.email;
                        
                        app.table_fileInfo.Data{9,2}{1} = app.settings.name;
                        app.table_fileInfo.Data{10,2}{1} = app.settings.email;
                    end
                end
                
                %update probe
                app.probe = id.probes;
                app.nProbes = length(app.probe);
                app.table_fileInfo.Data{3,2}{1} = num2str(app.nProbes);
                
                app.dropdown_probe1.Value = app.probe(1).type;
                app.dropdown_area1.Value = app.probe(1).area;
                app.dropdown_probe1.Enable=1;
                app.dropdown_area1.Enable=1;
                
                if app.nProbes == 2
                    app.dropdown_probe2.Value = app.probe(2).type;
                    app.dropdown_area2.Value = app.probe(2).area;
                    app.dropdown_area2.Enable = true;
                    app.dropdown_probe2.Enable = 1;
                    app.nChannels = app.probe(1).nChannels + app.probe(2).nChannels;
                    app.table_fileInfo.Data{4,2}{1} = [num2str(app.probe(1).nChannels) ', ' num2str(app.probe(2).nChannels)];
                else
                    app.dropdown_probe2.Value = 'none';
                    app.dropdown_probe2.Enable=0;
                    app.dropdown_area2.Value='none';
                    app.dropdown_area2.Enable=0;
                    app.nChannels = app.probe(1).nChannels;
                    app.table_fileInfo.Data{4,2}{1} = num2str(app.probe(1).nChannels);
                end
            end
            
            %proceed to reading data
            fileinfo = dir(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_amplifier.dat']));
            app.nTotalSamples = fileinfo.bytes/(2*app.nChannels);
            
            %initialize
            app.badChannels = zeros(1,app.nChannels);
            app.thresholds = ones(1,app.nChannels).*(-200);
            
            %check whether file has already been thresholded and load those
            %settings
            oldExptFileExists = exist(fullfile(app.settings.expFolder,app.exptName,'experiment.mat'),'file');
            threshFileExists = exist(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_threshold.mat']),'file');
            
            if oldExptFileExists && ~threshFileExists %for backwards compatibility
                load(fullfile(app.settings.expFolder,app.exptName,'experiment.mat'),'b1','a1','BadCh','Th')
                app.butterParams.b1 = b1;
                app.butterParams.a1 = a1;
                app.badChannels = BadCh;
                app.thresholds(BadCh == 0) = Th;
                app.table_fileInfo.Data{3,2}{1} = num2str(sum(app.badChannels));
            end
            
            if threshFileExists
                load(fullfile(app.settings.expFolder,app.exptName,[app.exptName '_threshold.mat']),'thresholding')
                app.thresholds = thresholding.thresholds;
                app.badChannels = thresholding.badChannels;
                app.butterParams = thresholding.butter;
                app.settings.filterHighPass=app.butterParams.highPass;
                app.settings.filterLowPass=app.butterParams.lowPass;
                
                app.table_fileInfo.Data{6,2}{1} = num2str(sum(app.badChannels));
                app.table_fileInfo.Data{7,2}{1} = num2str(app.settings.filterHighPass);
                app.table_fileInfo.Data{8,2}{1} = num2str(app.settings.filterLowPass);
            end
            
            if ~isfield(app,'butterParams')
                [app.butterParams.b1, app.butterParams.a1] = butter(3, [app.settings.filterHighPass/app.sampleFreq,app.settings.filterLowPass/app.sampleFreq]*2, 'bandpass');
                app.butterParams.highPass = app.settings.filterHighPass;
                app.butterParams.lowPass = app.settings.filterLowPass;
            end
            
            %plot probe (if possible)
            app.currentChannel = 1;
            app.currentProbeNum = 1;
            app.refreshProbe();
            if app.nProbes==1
                app.button_nextProbe.Enable=0;
                app.button_prevProbe.Enable=0;
            else
                app.button_nextProbe.Enable=1;
                app.button_prevProbe.Enable=1;
            end
            
            %plot snippets
            app.refreshSnippet(true,'random');
            
            %enable snippet selection etc
            app.slider_threshold.Value = app.thresholds(1);
            app.slider_threshold.Enable=1;
            app.button_firstSnippet.Enable=1;
            app.button_lastSnippet.Enable=1;
            app.button_randomSnippet.Enable=1;
            app.button_timePrev.Enable=1;
            app.button_timeNext.Enable=1;
            app.button_nextChan.Enable=1;
            app.button_prevChan.Enable=1;
            app.button_rejectChan.Enable=1;
            app.button_save.Enable=1;


        end

        % Value changed function: dropdown_probe1
        function dropdown_probe1ValueChanged(app, event)
            type = app.dropdown_probe1.Value;
            if strcmp(type,'single')
                app.probe(1).channels = 0;
                app.probe(1).x = 0;
                app.probe(1).y = 0;
                app.probe(1).z = 0;
                app.probe(1).shaft = 1;
                app.probe(1).tipelectrode = 1;
                app.probe(1).type = type;
                app.probe(1).wiring = [0 0 0 0 1];
                app.probe(1).nShanks = 1;
                app.probe(1).config = 1;
            elseif strcmp(type,'tetrode')
                app.probe(1).channels = [0 1 2 3]';
                app.probe(1).x = 20*[-1 -1 1 1]';
                app.probe(1).y = [0 0 0 0]';
                app.probe(1).z = 20*[-1 1 -1 1]';
                app.probe(1).shaft = [1 1 1 1]';
                app.probe(1).tipelectrode = 1;
                app.probe(1).type = type;
                app.probe(1).wiring = [app.probe(1).channels app.probe(1).x app.probe(1).y app.probe(1).z app.probe(1).shaft];
                app.probe(1).nShanks = 1;
                app.probe(1).config = [1 2 3 4];
            elseif strcmp(type,'select')
                app.probe(1) = [];
            else
                eval(['probe_' type]); close;
                
                app.probe(1).channels = s.channels;
                app.probe(1).x = s.x;
                app.probe(1).y = s.y;
                app.probe(1).z = s.z;
                app.probe(1).shaft = s.shaft;
                app.probe(1).tipelectrode = s.tipelectrode;
                app.probe(1).type = type;
                app.probe(1).wiring = probewiring;
                app.probe(1).nShanks = length(unique(app.probe(1).shaft));
                app.probe(1).nChannels = numel(app.probe(1).channels);
                
                a = app.probe(1).wiring(app.probe(1).wiring(:,5) == 1,[1 4]);
                [~,idx] = sort(a(:,2),'descend');
                app.probe(1).config = a(idx,1);
                
                a = app.probe(1).wiring(app.probe(1).wiring(:,5) == 2,[1 4]);
                [~,idx] = sort(a(:,2),'descend');
                app.probe(1).config = [app.probe(1).config; a(idx,1)]';
            end
            
            if ~isempty(app.probe)
                app.refreshProbe();
            else
                cla(app.axis_probe);
            end
        end

        % Value changed function: dropdown_probe2
        function dropdown_probe2ValueChanged(app, event)
            type = app.dropdown_probe2.Value;
            
            if strcmp(type,'single')
                app.probe(2).channels = 0;
                app.probe(2).x = 0;
                app.probe(2).y = 0;
                app.probe(2).z = 0;
                app.probe(2).shaft = 1;
                app.probe(2).tipelectrode = 1;
                app.probe(2).type = type;
                app.probe(2).wiring = [0 0 0 0 1];
                app.probe(2).nShanks = 1;
                app.probe(2).config = 1;
            elseif strcmp(type,'tetrode')
                app.probe(2).channels = [0 1 2 3]';
                app.probe(2).x = 20*[-1 -1 1 1]';
                app.probe(2).y = [0 0 0 0]';
                app.probe(2).z = 20*[-1 1 -1 1]';
                app.probe(2).shaft = [1 1 1 1]';
                app.probe(2).tipelectrode = 1;
                app.probe(2).type = type;
                app.probe(2).wiring = [app.probe(1).channels app.probe(1).x app.probe(1).y app.probe(1).z app.probe(1).shaft];
                app.probe(2).nShanks = 1;
                app.probe(2).config = [1 2 3 4];
            elseif strcmp(type,'select')
                app.probe(2) = [];
            else
                eval(['probe_' type]); close;
                
                app.probe(2).channels = s.channels;
                app.probe(2).x = s.x;
                app.probe(2).y = s.y;
                app.probe(2).z = s.z;
                app.probe(2).shaft = s.shaft;
                app.probe(2).tipelectrode = s.tipelectrode;
                app.probe(2).type = type;
                app.probe(2).wiring = probewiring;
                app.probe(2).nShanks = length(unique(app.probe(2).shaft));
                
                a = app.probe(2).wiring(app.probe(2).wiring(:,5) == 1,[1 4]);
                [~,idx] = sort(a(:,2),'descend');
                app.probe(2).config = a(idx,1);
                
                a = app.probe(2).wiring(app.probe(2).wiring(:,5) == 2,[1 4]);
                [~,idx] = sort(a(:,2),'descend');
                app.probe(2).config = [app.probe(2).config; a(idx,1)]';
            end
            
        end

        % Value changed function: dropdown_area1
        function dropdown_area1ValueChanged(app, event)
            app.probe(1).area = app.dropdown_area1.Value;
        end

        % Value changed function: dropdown_area2
        function dropdown_area2ValueChanged(app, event)
            app.probe(2).area = app.dropdown_area2.Value;
        end

        % Button pushed function: button_nextProbe
        function button_nextProbeButtonPushed(app, event)
            if length(app.probe) == 1
                app.currentProbeNum = 1;
            else
                app.currentProbeNum = 3-app.currentProbeNum;
            end
            app.label_selectedProbe.Text = num2str(app.currentProbeNum);
            app.refreshProbe();
        end

        % Button pushed function: button_prevProbe
        function button_prevProbeButtonPushed(app, event)
            if length(app.probe) == 1
                app.currentProbeNum = 1;
            else
                app.currentProbeNum = 3-app.currentProbeNum;
            end
            app.label_selectedProbe.Text = num2str(app.currentProbeNum);
            app.refreshProbe();
        end

        % Button pushed function: button_save
        function button_saveButtonPushed(app, event)
            if ~exist([app.settings.expFolder '/' app.exptName '/SpikeFiles'],'dir')
                mkdir([app.settings.expFolder '/' app.exptName '/SpikeFiles'])
            end
            
            % for backward compatibility, for now
            experiment = app.exptName;
            Th = app.thresholds(~app.badChannels);
            BadCh = app.badChannels;
            b1 = app.butterParams.b1;
            a1 = app.butterParams.a1;
            isBR = 0;
            probes = {app.probe.type};
            if length(app.probe) == 1
                CHs = app.probe(1).config;
            else
                CHs = [app.probe(1).config app.probe(2).config];
            end
            save([app.settings.expFolder '/' app.exptName '/experiment.mat'],'experiment','Th','CHs','BadCh','b1','a1','isBR','probes');
            
            % the new way
            thresholding.thresholds = app.thresholds;
            thresholding.badChannels = app.badChannels;
            thresholding.butter = app.butterParams;
            
            %check that probe is fully set before saving
            
            id.exptId = app.exptName;
            id.probes = app.probe;
            id.name = app.settings.name;
            id.email = app.settings.email;
            id.isBR = false;
            id.sampleFreq = app.sampleFreq;
            
            save([app.settings.expFolder '/' app.exptName '/' app.exptName '_threshold.mat'],'thresholding');
            save([app.settings.expFolder '/' app.exptName '/' app.exptName '_id.mat'],'id');
            
            msgbox('Thresholds and id saved.','Saved','non-modal')
        end

        % Value changed function: slider_threshold
        function slider_thresholdChanged(app, event)
            app.thresholds(app.currentChannel) = event.Value;
            app.plotThreshold();
        end

        % Button pushed function: button_nextChan
        function button_nextChanButtonPushed(app, event)
            if app.currentChannel < app.nChannels
                app.currentChannel = app.currentChannel + 1;
                app.text_channelNum.Value = app.currentChannel;
                app.slider_threshold.Value = app.thresholds(app.currentChannel);
                
                if app.currentChannel > app.probe(1).nChannels && app.currentProbeNum == 1
                    app.currentProbeNum = 2;
                    app.refreshProbe();
                end
                app.refreshSnippet(false);
            end
        end

        % Button pushed function: button_prevChan
        function button_prevChanButtonPushed(app, event)
            if app.currentChannel > 1
                app.currentChannel = app.currentChannel - 1;
                app.text_channelNum.Value = app.currentChannel;
                app.slider_threshold.Value = app.thresholds(app.currentChannel);
                if app.currentChannel <= app.probe(1).nChannels && app.currentProbeNum == 2
                    app.currentProbeNum = 1;
                    app.refreshProbe();
                end
                app.refreshSnippet(false);
            end
        end

        % Button pushed function: button_rejectChan
        function button_rejectChanPushed(app, event)
            if app.badChannels(app.currentChannel)
                app.badChannels(app.currentChannel) = 0;
                app.button_rejectChan.Text = 'Reject Channel';
            else
                app.badChannels(app.currentChannel) = 1;
                app.button_rejectChan.Text = 'Accept Channel';
            end
            app.table_fileInfo.Data{3,2}{1} = num2str(sum(app.badChannels));
            app.refreshSnippet(false)
        end

        % Value changed function: text_channelNum
        function text_channelNumValueChanged(app, event)
            app.currentChannel = app.text_channelNum.Value;
            if app.currentChannel > app.probe(1).nChannels && length(app.probe) > 1
                app.currentProbeNum = 2;
            elseif app.currentChannel <= app.probe(1).nChannels
                app.currentProbeNum = 1;
            else
                app.currentChannel = event.PreviousValue;
                app.text_channelNum.Value = event.PreviousValue;
                app.currentProbeNum = 1;
            end
            app.refreshProbe();
            app.refreshSnippet(false);
        end

        % Button pushed function: button_firstSnippet
        function button_firstSnippetButtonPushed(app, event)
            app.refreshSnippet(true,'first');
        end

        % Button pushed function: button_lastSnippet
        function button_lastSnippetButtonPushed(app, event)
            app.refreshSnippet(true,'last');
        end

        % Button pushed function: button_randomSnippet
        function button_randomSnippetPushed(app, event)
            app.refreshSnippet(true,'random');
        end

        % Button pushed function: button_timePrev
        function button_timePrevButtonPushed(app, event)
            app.refreshSnippet(true,'prev');
        end

        % Button pushed function: button_timeNext
        function button_timeNextButtonPushed(app, event)
            app.refreshSnippet(true,'next');
        end

        % Selection changed function: radio_compSelect
        function radio_compSelectSelectionChanged(app, event)
            selectedButton = app.radio_compSelect.SelectedObject;
            
            if strcmp(selectedButton.Text,'remote')
                app.settings.doRemote = true;
            else
                app.settings.doRemote = false;
            end
            
            app.refreshRemoteInfo();
        end

        % Value changed function: text_ip
        function text_ipValueChanged(app, event)
            app.settings.remoteIP = app.text_ip.Value;
            app.refreshRemoteInfo();
        end

        % Button pushed function: button_checkConn
        function button_checkConnButtonPushed(app, event)
            % [] = system(['ssh nielsenlab@' app.settings.remoteIP]);
        end

        % Button pushed function: button_copyData
        function button_copyDataButtonPushed(app, event)
            
        end

        % Button pushed function: button_extract
        function button_extractButtonPushed(app, event)
            if app.settings.doRemote
                
            else
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThresholdGUIUIFigure = uifigure('Visible', 'off');
            app.ThresholdGUIUIFigure.Name = 'Threshold GUI';
            app.ThresholdGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ThresholdGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.panel_location = uipanel(app.LeftPanel);
            app.panel_location.Title = 'File';
            app.button_getExpt = uibutton(app.panel_location, 'push');
            app.button_getExpt.ButtonPushedFcn = createCallbackFcn(app, @button_getExptPushed, true);
            app.button_getExpt.Text = 'Get Experiment';
            app.button_save = uibutton(app.panel_location, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @button_saveButtonPushed, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save Thresholds';
            app.ProbeAreaLabel_2 = uilabel(app.panel_location);
            app.ProbeAreaLabel_2.Text = 'Probe/Area';
            app.dropdown_probe2 = uidropdown(app.panel_location);
            app.dropdown_probe2.Items = {'select', '64D', '64E', '64F', '64G', '64H', '64M', '128P', 'single', 'tetrode', ''};
            app.dropdown_probe2.ValueChangedFcn = createCallbackFcn(app, @dropdown_probe2ValueChanged, true);
            app.dropdown_probe2.Enable = 'off';
            app.dropdown_probe2.Value = 'select';
            app.ProbeAreaLabel = uilabel(app.panel_location);
            app.ProbeAreaLabel.Text = 'Probe/Area';
            app.dropdown_probe1 = uidropdown(app.panel_location);
            app.dropdown_probe1.Items = {'select', '64D', '64E', '64F', '64G', '64H', '64M', '128P', 'single', 'tetrode'};
            app.dropdown_probe1.ValueChangedFcn = createCallbackFcn(app, @dropdown_probe1ValueChanged, true);
            app.dropdown_probe1.Enable = 'off';
            app.dropdown_probe1.Value = 'select';
            app.dropdown_area1 = uidropdown(app.panel_location);
            app.dropdown_area1.Items = {'select', 'V1', 'PSS', 'LGN', 'S1', 'other'};
            app.dropdown_area1.ValueChangedFcn = createCallbackFcn(app, @dropdown_area1ValueChanged, true);
            app.dropdown_area1.Enable = 'off';
            app.dropdown_area1.Value = 'select';
            app.dropdown_area2 = uidropdown(app.panel_location);
            app.dropdown_area2.Items = {'select', 'V1', 'PSS', 'LGN', 'S1', 'other'};
            app.dropdown_area2.ValueChangedFcn = createCallbackFcn(app, @dropdown_area2ValueChanged, true);
            app.dropdown_area2.Enable = 'off';
            app.dropdown_area2.Value = 'select';
            app.SettingsButton = uibutton(app.panel_location, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @button_settingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.panel_fileInfo = uipanel(app.LeftPanel);
            app.panel_fileInfo.Title = 'File Details';
            app.table_fileInfo = uitable(app.panel_fileInfo);
            app.table_fileInfo.ColumnName = {'Name'; 'Prop'};
            app.table_fileInfo.RowName = {''; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.panel_signal = uipanel(app.RightPanel);
            app.axis_snippet = uiaxes(app.panel_signal);
            xlabel(app.axis_snippet, 'Time (ms)')
            ylabel(app.axis_snippet, 'Signal (mV)')
            app.ThresholdSliderLabel = uilabel(app.panel_signal);
            app.ThresholdSliderLabel.Enable = 'off';
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.slider_threshold = uislider(app.panel_signal);
            app.slider_threshold.Limits = [-500 500];
            app.slider_threshold.MajorTicks = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.slider_threshold.ValueChangedFcn = createCallbackFcn(app, @slider_thresholdChanged, true);
            app.slider_threshold.MinorTicks = [-500 -490 -480 -470 -460 -450 -440 -430 -420 -410 -400 -390 -380 -370 -360 -350 -340 -330 -320 -310 -300 -290 -280 -270 -260 -250 -240 -230 -220 -210 -200 -190 -180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300 310 320 330 340 350 360 370 380 390 400 410 420 430 440 450 460 470 480 490 500];
            app.slider_threshold.Enable = 'off';
            app.slider_threshold.Value = -200;
            app.button_randomSnippet = uibutton(app.panel_signal, 'push');
            app.button_randomSnippet.ButtonPushedFcn = createCallbackFcn(app, @button_randomSnippetPushed, true);
            app.button_randomSnippet.Enable = 'off';
            app.button_randomSnippet.Text = 'Random';
            app.button_nextChan = uibutton(app.panel_signal, 'push');
            app.button_nextChan.ButtonPushedFcn = createCallbackFcn(app, @button_nextChanButtonPushed, true);
            app.button_nextChan.Enable = 'off';
            app.button_nextChan.Text = '>';
            app.button_prevChan = uibutton(app.panel_signal, 'push');
            app.button_prevChan.ButtonPushedFcn = createCallbackFcn(app, @button_prevChanButtonPushed, true);
            app.button_prevChan.Enable = 'off';
            app.button_prevChan.Text = '<';
            app.button_rejectChan = uibutton(app.panel_signal, 'push');
            app.button_rejectChan.ButtonPushedFcn = createCallbackFcn(app, @button_rejectChanPushed, true);
            app.button_rejectChan.Enable = 'off';
            app.button_rejectChan.Text = 'Reject Channel';
            app.axis_spikePreview = uiaxes(app.panel_signal);
            xlabel(app.axis_spikePreview, 'Time (ms)')
            ylabel(app.axis_spikePreview, 'Signal (mV)')
            app.panel_extract = uipanel(app.panel_signal);
            app.panel_extract.Tooltip = {'Work in Progress'};
            app.radio_compSelect = uibuttongroup(app.panel_extract);
            app.radio_compSelect.SelectionChangedFcn = createCallbackFcn(app, @radio_compSelectSelectionChanged, true);
            app.radio_local = uiradiobutton(app.radio_compSelect);
            app.radio_local.Enable = 'off';
            app.radio_local.Text = 'local';
            app.radio_local.Value = true;
            app.radio_remote = uiradiobutton(app.radio_compSelect);
            app.radio_remote.Enable = 'off';
            app.radio_remote.Text = 'remote';
            app.remoteipEditFieldLabel = uilabel(app.panel_extract);
            app.remoteipEditFieldLabel.Enable = 'off';
            app.remoteipEditFieldLabel.Text = 'remote ip';
            app.text_ip = uieditfield(app.panel_extract, 'text');
            app.text_ip.ValueChangedFcn = createCallbackFcn(app, @text_ipValueChanged, true);
            app.text_ip.Enable = 'off';
            app.button_checkConn = uibutton(app.panel_extract, 'push');
            app.button_checkConn.ButtonPushedFcn = createCallbackFcn(app, @button_checkConnButtonPushed, true);
            app.button_checkConn.Enable = 'off';
            app.button_checkConn.Text = 'check conn';
            app.button_copyData = uibutton(app.panel_extract, 'push');
            app.button_copyData.ButtonPushedFcn = createCallbackFcn(app, @button_copyDataButtonPushed, true);
            app.button_copyData.Enable = 'off';
            app.button_copyData.Text = 'copy data';
            app.button_extract = uibutton(app.panel_extract, 'push');
            app.button_extract.ButtonPushedFcn = createCallbackFcn(app, @button_extractButtonPushed, true);
            app.button_extract.Enable = 'off';
            app.button_extract.Text = 'extract';
            app.button_firstSnippet = uibutton(app.panel_signal, 'push');
            app.button_firstSnippet.ButtonPushedFcn = createCallbackFcn(app, @button_firstSnippetButtonPushed, true);
            app.button_firstSnippet.Enable = 'off';
            app.button_firstSnippet.Text = 'First';
            app.button_lastSnippet = uibutton(app.panel_signal, 'push');
            app.button_lastSnippet.ButtonPushedFcn = createCallbackFcn(app, @button_lastSnippetButtonPushed, true);
            app.button_lastSnippet.Enable = 'off';
            app.button_lastSnippet.Text = 'Last';
            app.text_channelNum = uieditfield(app.panel_signal, 'numeric');
            app.text_channelNum.ValueChangedFcn = createCallbackFcn(app, @text_channelNumValueChanged, true);
            app.text_channelNum.Value = 1;
            app.button_timePrev = uibutton(app.panel_signal, 'push');
            app.button_timePrev.ButtonPushedFcn = createCallbackFcn(app, @button_timePrevButtonPushed, true);
            app.button_timePrev.Enable = 'off';
            app.button_timePrev.Text = '<';
            app.button_timeNext = uibutton(app.panel_signal, 'push');
            app.button_timeNext.ButtonPushedFcn = createCallbackFcn(app, @button_timeNextButtonPushed, true);
            app.button_timeNext.Enable = 'off';
            app.button_timeNext.Text = '>';
            app.JumptotimeLabel = uilabel(app.panel_signal);
            app.JumptotimeLabel.Text = 'Jump to (time):';
            app.ChannelLabel = uilabel(app.panel_signal);
            app.ChannelLabel.Text = 'Channel:';
            app.panel_probe = uipanel(app.RightPanel);
            app.axis_probe = uiaxes(app.panel_probe);
            title(app.axis_probe, '')
            xlabel(app.axis_probe, 'X')
            ylabel(app.axis_probe, 'Y')
            app.label_selectedProbe = uilabel(app.panel_probe);
            app.label_selectedProbe.Text = '1';
            app.button_nextProbe = uibutton(app.panel_probe, 'push');
            app.button_nextProbe.ButtonPushedFcn = createCallbackFcn(app, @button_nextProbeButtonPushed, true);
            app.button_nextProbe.Enable = 'off';
            app.button_nextProbe.Text = '>';
            app.button_prevProbe = uibutton(app.panel_probe, 'push');
            app.button_prevProbe.ButtonPushedFcn = createCallbackFcn(app, @button_prevProbeButtonPushed, true);
            app.button_prevProbe.Enable = 'off';
            app.button_prevProbe.Text = '<';
            app.ProbeLabel = uilabel(app.panel_probe);
            app.ProbeLabel.Text = 'Probe:';
            % Show the figure after all components are created
            app.ThresholdGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thresholdGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThresholdGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThresholdGUIUIFigure)
        end
    end
end